classdef CV_challenge < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AboutMenu                     matlab.ui.container.Menu
        UITable                       matlab.ui.control.Table
        detectcameraParButton         matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        ReadyLamp_3                   matlab.ui.control.Lamp
        ReadyLamp_3Label              matlab.ui.control.Label
        ReadyLamp_2                   matlab.ui.control.Lamp
        ReadyLamp_2Label              matlab.ui.control.Label
        ReadyLamp                     matlab.ui.control.Lamp
        ReadyLampLabel                matlab.ui.control.Label
        imageSizeEditField_2          matlab.ui.control.NumericEditField
        principalPointEditField_2     matlab.ui.control.NumericEditField
        focalLengthEditField_2        matlab.ui.control.NumericEditField
        imageSizeEditField            matlab.ui.control.NumericEditField
        imageSizeEditFieldLabel       matlab.ui.control.Label
        principalPointEditField       matlab.ui.control.NumericEditField
        principalPointEditFieldLabel  matlab.ui.control.Label
        focalLengthEditField          matlab.ui.control.NumericEditField
        focalLengthEditFieldLabel     matlab.ui.control.Label
        ConfirmButton                 matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        CloseButton                   matlab.ui.control.Button
        SelectImagesButton            matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        images
        intrinsics
        imds
        SelectImagesButtonPressed = false;
        ConfirmButtonPressed = false;
        detectcameraParButtonPressed = false;
        opts = struct('WindowStyle','modal',... 
              'Interpreter','tex');
        opts1 = struct('Default','Cancel',... 
              'Interpreter','tex');
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImagesButton
        function SelectImagesButtonPushed(app, event)
            [folderPath] = uigetdir('','Select a folder');
            if isequal(folderPath,0)
                errordlg("\fontsize{16}No images selected","error",app.opts);
                return;
            else
                imageDir = fullfile(folderPath);
                app.imds = imageDatastore(imageDir);
            end
            app.ReadyLamp_2.Color = "0.00,1.00,0.00";
            app.SelectImagesButtonPressed = true;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %% Initialization
            % lamp
            if ~app.SelectImagesButtonPressed
                errordlg("\fontsize{16}Please select images first.","error",app.opts);
                return;
            elseif ~app.ConfirmButtonPressed
                errordlg("\fontsize{16}Please comfirm the parameters of the camera.","error",app.opts);
                return;
            end
                        
            % waitbar init
            waitBar1 = waitbar(0,'Preparing...','Name','Reconstruction');

            % variable
            test = 10;
            app.images = cell(1, numel(app.imds.Files));
            for i = 1:numel(app.imds.Files)
                I = readimage(app.imds, i);
                I =im2gray(I);
                I = imresize(I, app.intrinsics.ImageSize);
                app.images{i}= medfilt2(I);
            end
            num_img =numel(app.imds.Files);
            Points =cell(1, num_img);
            Features=cell(1, num_img);
            Points{1}   = detectSIFTFeatures(app.images{1});
            [Features{1}, Points{1}]= extractFeatures(app.images{1}, Points{1});
            
            %% establish vSet。
            vSet = imageviewset;
            viewId = 1;
            vSet = addView(vSet, viewId, rigidtform3d, Points=Points{1});
            
            %% core
            %tstart = tic;
            for i =  2:num_img
            %for i =  2:test
                % waitbar
                pause(0.01)
                %tnow = toc(tstart);
                %trem = tnow/i*(num_img-i);
                % tips = {['Progress:',num2str(i),'/',num2str(num_img)];
                %     ['Remaining Time:',num2str(round(trem,1))]};
                tips = ['Progress:',num2str(i),'/',num2str(num_img)];
                waitbar(i/num_img,waitBar1,tips);

                % compute
                Points{i}= detectSIFTFeatures(app.images{i});
                [Features{i}, Points{i}]= extractFeatures(app.images{i}, Points{i});

                max_Pairs=0;
                for j =1:i-1
                    indexPairs  = matchFeatures(Features{i-j}, Features{i});                
                    if numel(indexPairs) >max_Pairs
                        max_Pairs=numel(indexPairs);
                        k=j;
                        indexPairs_k=indexPairs;
                    end
                end
                
                matchedPoints1 = Points{i-k}(indexPairs_k(:, 1));
                matchedPoints2 = Points{i}(indexPairs_k(:, 2));
            
                [E, inlierIdx] = estimateEssentialMatrix(matchedPoints1.Location, matchedPoints2.Location,...
                    app.intrinsics);  
                inlierPoints1 = matchedPoints1.Location(inlierIdx, :);
                inlierPoints2 = matchedPoints2.Location(inlierIdx, :);        
                [relPose, validPointFraction] = ...
                estrelpose(E, app.intrinsics, inlierPoints1(1:2:end, :),...
                inlierPoints2(1:2:end, :));          
                prevPose = poses(vSet, i-k).AbsolutePose;
                currPose = rigidtform3d(prevPose.A*relPose(1).A);      
                vSet = addView(vSet, i, currPose, Points=Points{i});  
                vSet = addConnection(vSet, i-k, i, relPose(1), Matches=indexPairs_k(inlierIdx,:));         
                tracks = findTracks(vSet);    
                camPoses = poses(vSet);     
                xyzPoints = triangulateMultiview(tracks, camPoses, app.intrinsics);     
                [xyzPoints, camPoses, reprojectionErrors] = bundleAdjustment(xyzPoints, ...
                    tracks, camPoses, app.intrinsics, FixedViewId=1, ...
                    PointsUndistorted=true);      
                vSet = updateView(vSet, camPoses);
            end
            %toc
            close(waitBar1);
            
            %% show
            axesHandle = app.UIAxes;

            %% xyzPoints
            % Exclude noisy 3-D points.
            goodIdx = (reprojectionErrors < 5);
            xyzPoints = xyzPoints(goodIdx, :);
            xyzPoints1 = xyzPoints';
            figure;
            pcshow(xyzPoints, VerticalAxis='y', VerticalAxisDir='down', MarkerSize= 45);
            hold on;

            %sz=10;
            %scatter3(axesHandle,xyzPoints1(1,:),xyzPoints1(2,:),xyzPoints1(3,:),sz,'filled');
            %grid(axesHandle,"on") 
            %hold(axesHandle,"on") 
            

            %%  Point cloud processing
            waitBar2 = waitbar(0,'Processing...','Name','Point cloud');
            ptCloud = pointCloud(xyzPoints);
            ptCloud = pcdenoise(ptCloud);
            % parameter, adjustable, which determines which points are considered to be part of the same object
            distanceThreshold = 1.0; 
            % parameter, which can be adjusted, determines the minimum number of points needed for an object
            numClusterPoints = 10;  
            
            % Splitting point clouds using the pcsegdist function
            [labels,numClusters] = pcsegdist(ptCloud, distanceThreshold, 'NumClusterPoints', numClusterPoints);
            
            %  idxValidPoints = find(labels);
            % labelColorIndex = labels(idxValidPoints);
            
            % Using labels to segment point clouds
             segmentedClouds = cell(numClusters,1);
            for i = 1:numClusters
                indices = find(labels == i);
                segmentedClouds{i} = select(ptCloud, indices);
            end
            figure;
            for i = 1:numel(segmentedClouds)
                pcshow(segmentedClouds{i});
                hold on;
            end
            hold off;
            % parameter that determines the minimum number of points needed to create an alpha shape
            minNumPointsForAlphaShape = 4;  
            
            % Define the colour mapping (this can be modified to use a different colour)
            colors = jet(numel(segmentedClouds));
            %% ....
            % Create a new figure
            volume=cell(1,numel(segmentedClouds));
            distanc=cell(1,numel(segmentedClouds));
            
            
            % Create an alpha shape for each cluster and plot it on the same graph
            for ik = 1:numel(segmentedClouds)
                % waitbar2
                pause(0.01);
                tips = ['Progress:',num2str(ik),'/',num2str(numel(segmentedClouds))];
                waitbar(ik/numel(segmentedClouds),waitBar2,tips);
                % Extracts the points of the current cluster and converts them to double type
                xyzPointsCurrent = double(segmentedClouds{ik}.Location);
            
                % Verify that the number of points
                if size(xyzPointsCurrent, 1) < minNumPointsForAlphaShape
                    continue;
                end
            
                minX = min(xyzPointsCurrent(:, 1));
                maxX = max(xyzPointsCurrent(:, 1));
                minY = min(xyzPointsCurrent(:, 2));
                maxY = max(xyzPointsCurrent(:, 2));
                minZ = min(xyzPointsCurrent(:, 3));
                maxZ = max(xyzPointsCurrent(:, 3));
                vertices = [minX, minY, minZ;
                            maxX, minY, minZ;
                            maxX, maxY, minZ;
                            minX, maxY, minZ;
                            minX, minY, maxZ;
                            maxX, minY, maxZ;
                            maxX, maxY, maxZ;
                            minX, maxY, maxZ];
                % Calculating the volume and position of an object
                edgeLengths = max(vertices) - min(vertices);
                volume{ik} = prod(edgeLengths);
                distanc{ik} =sqrt(((maxX+minX)/2)^2+((maxY+minY)/2)^2+((maxZ+minZ)/2)^2);
                hold(axesHandle, 'on');
                % 
                axis equal;
                axis equal
                xlabel('x')
                ylabel('y')
                zlabel('z(t)')
                plot3(axesHandle, vertices([1 2 3 4 1], 1), vertices([1 2 3 4 1], 2), vertices([1 2 3 4 1], 3), 'r-', 'LineWidth', 2);
                plot3(axesHandle, vertices([5 6 7 8 5], 1), vertices([5 6 7 8 5], 2), vertices([5 6 7 8 5], 3), 'r-', 'LineWidth', 2);
                plot3(axesHandle, [vertices(1, 1) vertices(5, 1)], [vertices(1, 2) vertices(5, 2)], [vertices(1, 3) vertices(5, 3)], 'r-', 'LineWidth', 2);
                plot3(axesHandle, [vertices(2, 1) vertices(6, 1)], [vertices(2, 2) vertices(6, 2)], [vertices(2, 3) vertices(6, 3)], 'r-', 'LineWidth', 2);
                plot3(axesHandle, [vertices(3, 1) vertices(7, 1)], [vertices(3, 2) vertices(7, 2)], [vertices(3, 3) vertices(7, 3)], 'r-', 'LineWidth', 2);
                plot3(axesHandle, [vertices(4, 1) vertices(8, 1)], [vertices(4, 2) vertices(8, 2)], [vertices(4, 3) vertices(8, 3)], 'r-', 'LineWidth', 2);
                text(axesHandle,(maxX+minX)/2,(maxY+minY)/2,(maxZ+minZ)/2,num2str(ik));
                app.UITable.Data(ik, 1) = ik;
                app.UITable.Data(ik, 2) = volume{ik};
                app.UITable.Data(ik, 3) = distanc{ik};
            
            end
            grid(axesHandle,"on") 
            % Find the furthest point cloud clusters and generate walls
            zMin = ptCloud.ZLimits(1);
            % zMax = ptCloud.ZLimits(2);
            zMax = max(cellfun(@(x) x.ZLimits(2), segmentedClouds));
            xMin = ptCloud.XLimits(1);
            xMax = ptCloud.XLimits(2);
            yMin = ptCloud.YLimits(1);
            yMax = ptCloud.YLimits(2);
            
            faces = [1, 2, 6, 5; 2, 3, 7, 6; 3, 4, 8, 7; 4, 1, 5, 8; 1, 2, 3, 4; 5, 6, 7, 8];
            faceColor = [245/255 245/255 220/255];
            wallz=[xMin,yMin,zMin;xMin,yMax,zMin;xMax,yMax,zMin;xMax,yMin,zMin;xMin,yMin,zMin-1;xMin,yMax,zMin-1;xMax,yMax,zMin-1;xMax,yMin,zMin-1];
            wallx_link=[xMin,yMin,zMin;xMin,yMin,zMax;xMin,yMax,zMax;xMin,yMax,zMin;xMin-1,yMin,zMin;xMin-1,yMin,zMax;xMin-1,yMax,zMax;xMin-1,yMax,zMin];
            wallx_recht=[xMax,yMin,zMin;xMax,yMin,zMax;xMax,yMax,zMax;xMax,yMax,zMin;xMax+1,yMin,zMin;xMax+1,yMin,zMax;xMax+1,yMax,zMax;xMax+1,yMax,zMin];
            wally_vor=[xMin,yMin,zMin;xMin,yMin,zMax;xMax,yMin,zMax;xMax,yMin,zMin;xMin,yMin-1,zMin;xMin,yMin-1,zMax;xMax,yMin-1,zMax;xMax,yMin-1,zMin];
            wally_hunter=[xMin,yMax,zMin;xMin,yMax,zMax;xMax,yMax,zMax;xMax,yMax,zMin;xMin,yMax+1,zMin;xMin,yMax+1,zMax;xMax,yMax+1,zMax;xMax,yMax+1,zMin];
            patch('parent',axesHandle,'Vertices', wallz, 'Faces', faces, 'FaceColor', faceColor, "FaceAlpha" , 0.5,'EdgeColor', 'none');
            patch('parent',axesHandle,'Vertices', wallx_link, 'Faces', faces, 'FaceColor', faceColor,"FaceAlpha" , 0.5, 'EdgeColor', 'none');
            patch('parent',axesHandle,'Vertices', wallx_recht, 'Faces', faces, 'FaceColor', faceColor,"FaceAlpha" , 0.1, 'EdgeColor', 'none');
            patch('parent',axesHandle,'Vertices', wally_vor, 'Faces', faces, 'FaceColor', faceColor,"FaceAlpha" , 0.1, 'EdgeColor', 'none');
            patch('parent',axesHandle,'Vertices', wally_hunter, 'Faces', faces, 'FaceColor', faceColor,"FaceAlpha" , 0.5, 'EdgeColor', 'none');
            hold(axesHandle, 'off');
            close(waitBar2);
            app.ReadyLamp_3.Color = "0.00,1.00,0.00";

        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            if ~app.detectcameraParButtonPressed
                errordlg("\fontsize{16}Please detect the parameters of the camera first.","error",app.opts);
                return;
            end
            f1=app.focalLengthEditField.Value;
            f2=app.focalLengthEditField_2.Value;
            p1=app.principalPointEditField.Value;
            p2=app.principalPointEditField_2.Value;
            im1=app.imageSizeEditField.Value;
            im2=app.imageSizeEditField_2.Value;
            if isequal(f1,0) && isequal(f2,0)
               focalLength = [3408.59,3408.87];
            else
               focalLength = [f1,f2];
            end
            if isequal(p1,0) && isequal(p2,0)
                principalPoint =[3117.24,2064.07];
            else
                principalPoint = [p1,p2];
            end
            if isequal(im1,0) && isequal(im2,0)
                imageSize = size(app.images{1});
            else
                imageSize = [im1,im2];
            end
            app.intrinsics = cameraIntrinsics(focalLength,principalPoint,imageSize);
            app.ReadyLamp.Color = "0.00,1.00,0.00";
            app.ConfirmButtonPressed = true;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            choice = questdlg('\fontsize{16}Do you want to close?','Close','Close','Cancel',app.opts1);
            switch choice
                case 'Close'
                    delete(app.UIFigure);
                    return;
                case 'Cancel'
                    return;
            end
        end

        % Button pushed function: ResetButton
        function ResetMenuSelected(app, event)
            choice = questdlg('\fontsize{16}Do you want to reset?','Reset','Reset','Cancel',app.opts1);
            switch choice
                case 'Reset'
                    app.ReadyLamp.Color = "1.00,0.00,0.00";
                    app.ReadyLamp_2.Color = "1.00,0.00,0.00";
                    app.ReadyLamp_3.Color = "1.00,0.00,0.00";
                    cla(app.UIAxes);
                    if ~isempty(app.UITable.Data)
                        app.UITable.Data(:, :) = [];
                        % app.UITable.Data(:, 2) = [];
                        % app.UITable.Data(:, 3) = [];
                    end
                    app.focalLengthEditField.Value = 0;
                    app.focalLengthEditField_2.Value = 0;
                    app.principalPointEditField.Value = 0;
                    app.principalPointEditField_2.Value = 0;
                    app.imageSizeEditField.Value = 0;
                    app.imageSizeEditField_2.Value = 0;
                    app.SelectImagesButtonPressed = false;
                    app.ConfirmButtonPressed = false;
                    app.detectcameraParButtonPressed = false;
                    return;
                case 'Cancel'
                    return;
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            msgbox("\fontsize{16}This is a demo.","About this application",app.opts)
        end

        % Button pushed function: detectcameraParButton
        function detectcameraParButtonPushed(app, event)
            folder = uigetdir('select the folder');
            if isequal(folder,0)
                errordlg("\fontsize{16}No folder selected","error",app.opts);
                return;
            end
            fileID = fullfile(folder, 'cameras.txt');
            if exist(fileID,'file') == 0
                msgbox("\fontsize{16}The camera parameters were not found in the current folder, please enter them manually.","error",app.opts)
                return;
            else
                camPar = readtable(fileID);
                camPar = table2array(camPar(2,3:8));
                app.focalLengthEditField.Value = camPar(1,3);
                app.focalLengthEditField_2.Value =camPar(1,4);
                app.principalPointEditField.Value = camPar(1,5);
                app.principalPointEditField_2.Value = camPar(1,6);
                app.imageSizeEditField.Value = camPar(1,2);
                app.imageSizeEditField_2.Value = camPar(1,1);
                msgbox("\fontsize{16}Camera parameters are entered.","Hint",app.opts)
            end
            app.detectcameraParButtonPressed = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AboutMenu = uimenu(app.UIFigure);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '3D Model')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.SelectImagesButton = uibutton(app.UIFigure, 'push');
            app.SelectImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImagesButtonPushed, true);
            app.SelectImagesButton.Text = 'Select Images';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.focalLengthEditFieldLabel = uilabel(app.UIFigure);
            app.focalLengthEditFieldLabel.Text = 'focalLength';
            app.focalLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.focalLengthEditField.ValueDisplayFormat = '%.2f';
            app.principalPointEditFieldLabel = uilabel(app.UIFigure);
            app.principalPointEditFieldLabel.Text = 'principalPoint';
            app.principalPointEditField = uieditfield(app.UIFigure, 'numeric');
            app.principalPointEditField.ValueDisplayFormat = '%.2f';
            app.imageSizeEditFieldLabel = uilabel(app.UIFigure);
            app.imageSizeEditFieldLabel.Text = 'imageSize';
            app.imageSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.imageSizeEditField.ValueDisplayFormat = '%.2f';
            app.focalLengthEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.focalLengthEditField_2.ValueDisplayFormat = '%.2f';
            app.principalPointEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.principalPointEditField_2.ValueDisplayFormat = '%.2f';
            app.imageSizeEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.imageSizeEditField_2.ValueDisplayFormat = '%.2f';
            app.ReadyLampLabel = uilabel(app.UIFigure);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.UIFigure);
            app.ReadyLamp_2Label = uilabel(app.UIFigure);
            app.ReadyLamp_2Label.Text = 'Ready';
            app.ReadyLamp_2 = uilamp(app.UIFigure);
            app.ReadyLamp_3Label = uilabel(app.UIFigure);
            app.ReadyLamp_3Label.Text = 'Ready';
            app.ReadyLamp_3 = uilamp(app.UIFigure);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMenuSelected, true);
            app.ResetButton.Text = 'Reset';
            app.detectcameraParButton = uibutton(app.UIFigure, 'push');
            app.detectcameraParButton.ButtonPushedFcn = createCallbackFcn(app, @detectcameraParButtonPushed, true);
            app.detectcameraParButton.Text = 'detect cameraPar';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Obj_name'; 'Volume[m^3]'; 'Distance[m]'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CV_challenge

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end